classdef SmithChart < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure                   % UI Figure
        UIAxes                  matlab.ui.control.UIAxes           % Smith ...
        Panel                   matlab.ui.container.Panel          % Parâme...
        LabelSlider             matlab.ui.control.Label            % R:
        Slider                  matlab.ui.control.Slider           % [0 10]
        NumericEditField        matlab.ui.control.NumericEditField % [0 Inf]
        Label                   matlab.ui.control.Label            % 10^
        NumericEditField2       matlab.ui.control.NumericEditField % [-Inf ...
        Label2                  matlab.ui.control.Label            % Ω
        Panel2                  matlab.ui.container.Panel         
        Label3                  matlab.ui.control.Label            % L:
        Slider2                 matlab.ui.control.Slider           % [0 10]
        Panel3                  matlab.ui.container.Panel         
        NumericEditField3       matlab.ui.control.NumericEditField % [0 Inf]
        Label4                  matlab.ui.control.Label            % 10^
        NumericEditField4       matlab.ui.control.NumericEditField % [-Inf ...
        Label5                  matlab.ui.control.Label            % H
        Label6                  matlab.ui.control.Label            % C:
        Slider3                 matlab.ui.control.Slider           % [0 10]
        Panel4                  matlab.ui.container.Panel         
        NumericEditField5       matlab.ui.control.NumericEditField % [0 Inf]
        Label7                  matlab.ui.control.Label            % 10^
        NumericEditField6       matlab.ui.control.NumericEditField % [-Inf ...
        Label8                  matlab.ui.control.Label            % F
        Label9                  matlab.ui.control.Label            % G:
        Slider4                 matlab.ui.control.Slider           % [0 10]
        Panel5                  matlab.ui.container.Panel         
        NumericEditField7       matlab.ui.control.NumericEditField % [0 Inf]
        Label10                 matlab.ui.control.Label            % 10^
        NumericEditField8       matlab.ui.control.NumericEditField % [-Inf ...
        Label11                 matlab.ui.control.Label            % S
        Panel6                  matlab.ui.container.Panel          % Carga ...
        Button                  matlab.ui.control.Button           % Calcul...
        LabelNumericEditField9  matlab.ui.control.Label            % Z.L:
        NumericEditField9       matlab.ui.control.NumericEditField % [0 Inf]
        LabelNumericEditField10 matlab.ui.control.Label            % + j
        NumericEditField10      matlab.ui.control.NumericEditField % [-Inf ...
        Button2                 matlab.ui.control.Button           % Plotar...
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Slider value changing function
        function updateR(app, event)
            app.NumericEditField.Value = event.Value;
        end

        % NumericEditField value changed function
        function updateSliderR(app)
            app.Slider.Value = app.NumericEditField.Value;
        end

        % Slider2 value changing function
        function updateL(app, event)
            app.NumericEditField3.Value = event.Value;
        end

        % NumericEditField3 value changed function
        function updateSliderL(app)
            app.Slider2.Value = app.NumericEditField3.Value;
        end

        % Slider3 value changing function
        function updateC(app, event)
            app.NumericEditField5.Value = event.Value;
        end

        % Slider4 value changing function
        function updateG(app, event)
            app.NumericEditField7.Value = event.Value;
        end

        % NumericEditField5 value changed function
        function updateSliderC(app)
            app.Slider3.Value = app.NumericEditField5.Value;
        end

        % NumericEditField7 value changed function
        function updateSliderG(app)
            app.Slider4.Value = app.NumericEditField7.Value;
        end

        % Button button pushed function
        function alert(app)
            uialert(app.UIFigure, 'File not found', 'Invalid File');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Smith Chart');
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Parâmetros Distribuídos';
            app.Panel.Units = 'pixels';
            app.LabelSlider = uilabel(app.Panel);
            app.LabelSlider.Text = 'R:';
            app.Slider = uislider(app.Panel);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @updateR, true);
            app.Panel2 = uipanel(app.Panel);
            app.Panel2.Units = 'pixels';
            app.NumericEditField = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField.ValueChangedFcn = createCallbackFcn(app, @updateSliderR);
            app.NumericEditField.Limits = [0 Inf];
            app.NumericEditField.ValueDisplayFormat = '%.3f';
            app.Label = uilabel(app.Panel2);
            app.Label.Text = '10^';
            app.NumericEditField2 = uieditfield(app.Panel2, 'numeric');
            app.NumericEditField2.ValueDisplayFormat = '%.0f';
            app.Label2 = uilabel(app.Panel2);
            app.Label2.Text = 'Ω';
            app.Label3 = uilabel(app.Panel);
            app.Label3.Text = 'L:';
            app.Slider2 = uislider(app.Panel);
            app.Slider2.Limits = [0 10];
            app.Slider2.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @updateL, true);
            app.Panel3 = uipanel(app.Panel);
            app.Panel3.Units = 'pixels';
            app.NumericEditField3 = uieditfield(app.Panel3, 'numeric');
            app.NumericEditField3.ValueChangedFcn = createCallbackFcn(app, @updateSliderL);
            app.NumericEditField3.Limits = [0 Inf];
            app.NumericEditField3.ValueDisplayFormat = '%.3f';
            app.Label4 = uilabel(app.Panel3);
            app.Label4.Text = '10^';
            app.NumericEditField4 = uieditfield(app.Panel3, 'numeric');
            app.NumericEditField4.ValueDisplayFormat = '%.0f';
            app.Label5 = uilabel(app.Panel3);
            app.Label5.Text = 'H';
            app.Label6 = uilabel(app.Panel);
            app.Label6.Text = 'C:';
            app.Slider3 = uislider(app.Panel);
            app.Slider3.Limits = [0 10];
            app.Slider3.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Slider3.ValueChangingFcn = createCallbackFcn(app, @updateC, true);
            app.Panel4 = uipanel(app.Panel);
            app.Panel4.Units = 'pixels';
            app.NumericEditField5 = uieditfield(app.Panel4, 'numeric');
            app.NumericEditField5.ValueChangedFcn = createCallbackFcn(app, @updateSliderC);
            app.NumericEditField5.Limits = [0 Inf];
            app.NumericEditField5.ValueDisplayFormat = '%.3f';
            app.Label7 = uilabel(app.Panel4);
            app.Label7.Text = '10^';
            app.NumericEditField6 = uieditfield(app.Panel4, 'numeric');
            app.NumericEditField6.ValueDisplayFormat = '%.0f';
            app.Label8 = uilabel(app.Panel4);
            app.Label8.Text = 'F';
            app.Label9 = uilabel(app.Panel);
            app.Label9.Text = 'G:';
            app.Slider4 = uislider(app.Panel);
            app.Slider4.Limits = [0 10];
            app.Slider4.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.Slider4.ValueChangingFcn = createCallbackFcn(app, @updateG, true);
            app.Panel5 = uipanel(app.Panel);
            app.Panel5.Units = 'pixels';
            app.NumericEditField7 = uieditfield(app.Panel5, 'numeric');
            app.NumericEditField7.ValueChangedFcn = createCallbackFcn(app, @updateSliderG);
            app.NumericEditField7.Limits = [0 Inf];
            app.NumericEditField7.ValueDisplayFormat = '%.3f';
            app.Label10 = uilabel(app.Panel5);
            app.Label10.Text = '10^';
            app.NumericEditField8 = uieditfield(app.Panel5, 'numeric');
            app.NumericEditField8.ValueDisplayFormat = '%.0f';
            app.Label11 = uilabel(app.Panel5);
            app.Label11.Text = 'S';
            app.Button = uibutton(app.Panel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @alert);
            app.Button.Text = 'Calcular Z0 e γ';
            app.Panel6 = uipanel(app.UIFigure);
            app.Panel6.Title = 'Carga na Linha de Transmissão';
            app.Panel6.Units = 'pixels';
            app.LabelNumericEditField9 = uilabel(app.Panel6);
            app.LabelNumericEditField9.Text = 'Z.L:';
            app.NumericEditField9 = uieditfield(app.Panel6, 'numeric');
            app.NumericEditField9.Limits = [0 Inf];
            app.NumericEditField9.ValueDisplayFormat = '%.3f';
            app.LabelNumericEditField10 = uilabel(app.Panel6);
            app.LabelNumericEditField10.Text = '+ j';
            app.NumericEditField10 = uieditfield(app.Panel6, 'numeric');
            app.NumericEditField10.ValueDisplayFormat = '%.3f';
            app.Button2 = uibutton(app.Panel6, 'push');
            app.Button2.Text = 'Plotar Z.L';
        end
    end

    methods (Access = public)

        % Construct app
        function app = SmithChart()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end